classdef SUMO_Obj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SUMO_ObjUIFigure       matlab.ui.Figure
        NearVehiclesPanel      matlab.ui.container.Panel
        NearVehicle            matlab.ui.control.ListBox
        FollowerVehiclesPanel  matlab.ui.container.Panel
        FollowerVehicle        matlab.ui.control.ListBox
        joinReq                matlab.ui.control.Button
        leaveReq               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: leaveReq
        function leaveReqButtonPushed(app, event)
            % global PlatoonGUI;
            % global Leader;
            global Follower;
            value = app.NearVehicle.Value;
            
            for idx = 1:numel(Follower)
                if strcmp(Follower(idx).ID,value)
                    Follower(idx).sig = 2;
                elseif idx == numel(Follower)
                    error('LOSER, THERE IS SOMETHING WRONG');
                end
            end 
        end

        % Button pushed function: joinReq
        function joinReqButtonPushed(app, event)
            % global PlatoonGUI;
            global Leader;
            global Follower;
            
            value = app.NearVehicle.Value;
            if isa(Follower,'inf_veh')
                Follower(end+1)   = inf_veh(value,Leader.Pos,Leader.Ang);
                Follower(end).sig = 1;
            else
                Follower        = inf_veh(value,Leader.Pos,Leader.Ang);
                Follower(1).sig = 1;
            end
        end

        % Value changed function: NearVehicle
        function NearVehicleValueChanged(app, event)
            value = app.NearVehicle.Value;
            app.joinReq.Enable = 'on';
            app.leaveReq.Enable = 'off';
        end

        % Value changed function: FollowerVehicle
        function FollowerVehicleValueChanged(app, event)
            value = app.FollowerVehicle.Value;
            app.joinReq.Enable = 'off';
            app.leaveReq.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SUMO_ObjUIFigure = uifigure;
            app.SUMO_ObjUIFigure.Name = 'SUMO_Obj';
            app.NearVehiclesPanel = uipanel(app.SUMO_ObjUIFigure);
            app.NearVehiclesPanel.Title = 'Near Vehicles';
            app.NearVehicle = uilistbox(app.NearVehiclesPanel);
            app.NearVehicle.Items = {};
            app.NearVehicle.ValueChangedFcn = createCallbackFcn(app, @NearVehicleValueChanged, true);
            app.NearVehicle.Value = {};
            app.FollowerVehiclesPanel = uipanel(app.SUMO_ObjUIFigure);
            app.FollowerVehiclesPanel.Title = 'Follower Vehicles';
            app.FollowerVehicle = uilistbox(app.FollowerVehiclesPanel);
            app.FollowerVehicle.Items = {};
            app.FollowerVehicle.ValueChangedFcn = createCallbackFcn(app, @FollowerVehicleValueChanged, true);
            app.FollowerVehicle.Value = {};
            app.joinReq = uibutton(app.SUMO_ObjUIFigure, 'push');
            app.joinReq.ButtonPushedFcn = createCallbackFcn(app, @joinReqButtonPushed, true);
            app.joinReq.Enable = 'off';
            app.joinReq.Text = 'Join';
            app.leaveReq = uibutton(app.SUMO_ObjUIFigure, 'push');
            app.leaveReq.ButtonPushedFcn = createCallbackFcn(app, @leaveReqButtonPushed, true);
            app.leaveReq.Enable = 'off';
            app.leaveReq.Text = 'Leave';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SUMO_Obj

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SUMO_ObjUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SUMO_ObjUIFigure)
        end
    end
end