classdef leader_Obj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FollowerVehiclesPanel  matlab.ui.container.Panel
        FollowerVehicle        matlab.ui.control.ListBox
        AcceptReq              matlab.ui.control.Button
        DeclineReq             matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: AcceptReq
        function AcceptReqButtonPushed(app, event)
            global checkSignal;
            % global PlatoonGUI;
            % global FollowerGUI;
            global Leader;
            global Follower;
            
            asd = cell(numel(Leader.Platoon),1);
            asd(:) = {''};
            
            for zxc = 1:numel(Leader.Platoon)
                asd(zxc) = {Leader.Platoon(zxc).ID};
            end
            
            for m = 1:numel(Follower)
                if strcmp(Follower(m).ID,Leader.Follower.To)
                    idx = m;
                    break
                end
            end
            
            if ismember(Leader.Follower.To,asd) && Leader.Follower.Type ~= 2
                Leader = Leader.getSignal('',0);
            
                app.EditField.Value = '';
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';
                return
            end
            
            switch Leader.Follower.Type
            case 0
                %nothing
            case 1
                Leader = Leader.sendSignal(Leader.Follower.To,3);

                Leader = Leader.getSignal('',0);
                zxc3  = 5
                app.EditField.Value = '';
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';
            case 2
                Leader = Leader.sendSignal(Leader.Follower.To,3);

                Leader = Leader.getSignal('',0);
            
                zxc4  = 4
                app.EditField.Value = '';
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';
            case 20
                tempID = Leader.ID;
                Leader.ID = Follower(idx).ID;
                Leader = Leader.update;
                Follower(idx) = [];
                Follower(end+1) = inf_veh(tempID,Leader.Pos,Leader.Ang);
                Follower(end).InPlatoon = 1;
                
                Leader.Platoon(end+1) = Follower(end);
                Leader = Leader.getSignal('',0);
                Leader.camera;
                loser = 20
                
                app.UIFigure.Name = Leader.ID;
            
                app.EditField.Value = '';
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';   
            end
        end

        % Button pushed function: DeclineReq
        function DeclineReqButtonPushed(app, event)
            global checkSignal;
            % global PlatoonGUI;
            % global FollowerGUI;
            global Leader;
            global Follower;
            
            
            % 
            % % 
            % % UD = get(handles.output,'UserData');
            % asd = cell(numel(Leader.Platoon),1);
            % asd(:) = {''};
            % 
            % for zxc = 1:numel(Leader.Platoon)
            %     asd(zxc) = {Leader.Platoon(zxc).ID};
            % end
            
            for m = 1:numel(Follower)
                if strcmp(Follower(m).ID,Leader.Follower.To)
                    idx = m;
                    break
                end
            end
            
            % if ismember(Leader.Follower.To,asd)
            %     Leader = Leader.getSignal('',0);
            % 
            %     set(handles.textMessage,'String','');
            %     checkSignal = 0;
            %     set(handles.acceptReq ,'Enable','off');
            %     set(handles.declineReq,'Enable','off');
            %     return
            % end
            
            switch Leader.Follower.Type
            case 0
                %nothing
            case 1
                Leader = Leader.sendSignal(Leader.Follower.To,4);
            
                Leader = Leader.getSignal('',0);
                
                app.EditField.Value = '';
                zxc1  = 1
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';
            case 2
                Leader = Leader.sendSignal(Leader.Follower.To,4);
            
                Leader = Leader.getSignal('',0);
                zxc2  = 2
                app.EditField.Value = '';
            
            
                checkSignal = 0;
            
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';
            case 20
                tempID = Leader.ID;
                Leader.ID = Follower(idx).ID;
                Leader = Leader.update;
                Follower(idx) = [];
                Follower(end+1) = inf_veh(tempID,Leader.Pos,Leader.Ang);
                Follower(end).InPlatoon = 1;
                
                Leader.Platoon(end+1) = Follower(end);
                Leader = Leader.getSignal('',0);
                Leader.camera;
                loser = 5
              
                app.UIFigure.Name = Leader.ID;
            
            
                app.EditField.Value = '';
                checkSignal = 0;
                app.AcceptReq.Enable = 'off';
                app.DeclineReq.Enable = 'off';    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FollowerVehiclesPanel = uipanel(app.UIFigure);
            app.FollowerVehiclesPanel.Title = 'Follower Vehicles';
            app.FollowerVehicle = uilistbox(app.FollowerVehiclesPanel);
            app.FollowerVehicle.Items = {};
            app.FollowerVehicle.Value = {};
            app.AcceptReq = uibutton(app.UIFigure, 'push');
            app.AcceptReq.ButtonPushedFcn = createCallbackFcn(app, @AcceptReqButtonPushed, true);
            app.AcceptReq.Enable = 'off';
            app.AcceptReq.Text = 'Accept';
            app.DeclineReq = uibutton(app.UIFigure, 'push');
            app.DeclineReq.ButtonPushedFcn = createCallbackFcn(app, @DeclineReqButtonPushed, true);
            app.DeclineReq.Enable = 'off';
            app.DeclineReq.Text = 'Decline';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = leader_Obj

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end